classdef run_mmc_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel_4                  matlab.ui.container.Panel
        Panel_5                  matlab.ui.container.Panel
        DateandtimeLabel         matlab.ui.control.Label
        saveLabel                matlab.ui.control.Label
        ExperimentDropDownLabel  matlab.ui.control.Label
        ExperimentDropDown       matlab.ui.control.DropDown
        UserDropDownLabel        matlab.ui.control.Label
        UserDropDown             matlab.ui.control.DropDown
        RunExperimentButton      matlab.ui.control.StateButton
        Panel_6                  matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        AgeLabel                 matlab.ui.control.Label
        DobLabel                 matlab.ui.control.Label
        GenotypeLabel            matlab.ui.control.Label
        SexLabel                 matlab.ui.control.Label
        GroupLabel               matlab.ui.control.Label
        Panel_7                  matlab.ui.container.Panel
        MouseIDDropDownLabel     matlab.ui.control.Label
        MouseIDDropDown          matlab.ui.control.DropDown
        ExperimentHistoryPanel   matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
        ProgressTextLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        group
        dob
        sex
        genotype
        datetimenow 
        age
        basefolder
        mouseID
        name
        experiment
        savedir
        savefile
        data
        datetime
       
    end
    
    properties (Access = public)
        ph
        th
        metadata
    end
    
  
    methods (Access = private)
        
        function results = read_spreadsheet(app)
            %load spreadsheet
            base_folder = fileparts(mfilename('fullpath'));
            spreadsheet_filename = fullfile(base_folder, 'metadata_db.xlsx');
           
            %extract values from spreadsheet 
            [~,sUser] = xlsread(spreadsheet_filename, "User", 'A2:A100');
            [~,sExperiment] = xlsread(spreadsheet_filename, "Experiment", 'A2:A100');
            app.experiment = sExperiment;
            app.name = sUser;
            mouseID = xlsread(spreadsheet_filename, "Mouse", 'A2:A100');
            app.mouseID = string(mouseID);
            
            group = xlsread(spreadsheet_filename, "Mouse", 'B2:B100');
            sgroup = string(group);
            
            ndob = xlsread(spreadsheet_filename, "Mouse", 'c2:c100');
          
            if isempty(ndob)
                ndob = xlsread(spreadsheet_filename, "Mouse", 'C2:C100','basic');
            end
            
            sumdob = sum(ndob,2);
            chardob = datestr(sumdob+datenum('30-Dec-1899'));
            strdob = datetime(chardob,'InputFormat','dd-MMM-yyyy');
            sdob = string(strdob);
            nowt = datetime('now');
            timedate = string(between(strdob,nowt,'months'));
            
            [~,ssex] = xlsread(spreadsheet_filename, "Mouse", 'D2:D100');
            [~,sgenotype] = xlsread(spreadsheet_filename, "Mouse", 'E2:E100');
               
            app.group = sgroup;
            app.dob = sdob;
            app.sex = ssex;
            app.genotype = sgenotype;
            app.age = timedate;
           
            %values to app variables 
           
            app.UserDropDown.Items = app.name;
            app.UserDropDown.ItemsData = 1:200;
           
            app.ExperimentDropDown.ItemsData = 1:200;
            app.ExperimentDropDown.Items = app.experiment;
            
            app.MouseIDDropDown.Items = ['Select a Mouse'; app.mouseID];
           
            
            %set up dropdown index 
            app.MouseIDDropDown.ItemsData = 1:200;

            app.data.time = [89 ,74, 62, 49, 40, 31];


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.basefolder = fileparts(mfilename('fullpath'));
            read_spreadsheet(app);
            
            D = fullfile(app.basefolder, 'data');
            if exist (D,'dir')
        
            else
                mkdir(D);
            end
            app.savedir = D;
            app.saveLabel.Text = ['Save Directory: ' app.savedir];
            date = datestr(now,'mm-dd-yy HH-MM-SS');
            splitdate = split(date," ");
            
            
            app.DateandtimeLabel.Text = ['Date: ' num2str(splitdate{1}) '      Time: ' num2str(splitdate{2})];

            app.UIAxes.Visible = 'off';
            app.ProgressTextLabel.Text = '';
            
        end

        % Value changed function: RunExperimentButton
        function RunExperiment(app, event)
           
            value = app.RunExperimentButton.Value;
            D = app.savedir;
            N = datestr(now,'mm-dd-yy HH-MM-SS');
            app.metadata.datetime = N;
            app.savefile = [fullfile(D,N), '.mat'];
            app.metadata.savedir = fullfile(D,N);
            app.metadata.User = app.name{app.UserDropDown.Value};
            app.metadata.Experiment = app.experiment{app.ExperimentDropDown.Value};
 
            % Set up the progress bar axis
            app.UIAxes.Visible = 'on';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YLabel.String ='';
            app.UIAxes.XLabel.String ='';
            app.UIAxes.XLim = [0,100];
            
            % Create empty patch that will be updated
            app.ph = patch(app.UIAxes,[0 0 0 0],[0 0 2 2],[0.67578 1 0.18359]); %greenyellow
         
 
            %run selected experiment .m file
            expval = app.ExperimentDropDown.Value;
            cd experiment/
            expdir = pwd;
            runexp = fullfile(expdir,[app.ExperimentDropDown.Items{expval} '.m']);
            %disp(app.ExperimentDropDown.Items{expval})
            run(runexp);  
        end

        % Value changed function: MouseIDDropDown
        function SelectID(app, event)
            fvalue = app.MouseIDDropDown.Value;
            value = fvalue - 1;
            
            if app.MouseIDDropDown.ItemsData == 1
 
            else
            
            %Print Labels 
            app.GroupLabel.Text = 'Group: '+ app.group(value);
            app.DobLabel.Text = 'Date of Birth: '+app.dob(value); 
            app.SexLabel.Text = ['Sex: ' app.sex{value}];
            app.GenotypeLabel.Text =  ['Genotype: ' app.genotype{value}];
            app.AgeLabel.Text = ['Age: ' app.age{value} 'nths'];
           
            %mouse info into meta struct
            app.metadata.mouseID =  app.mouseID{value};
            app.metadata.mouseAge = [app.age{value} 'nths'];
            app.metadata.group =  app.group{value};
            app.metadata.dob = app.dob{value} ;
            app.metadata.sex = app.sex{value};
            app.metadata.genotype = app.genotype{value};
            
          end
         
          datalist = dir(fullfile(app.savedir,'*.mat'));
             history = cell(1,10);
             n = 0 ;
          for i = 1:length(datalist)
            M = load(fullfile(app.savedir,datalist(i).name));
            
            column = fieldnames(M.exp.metadata);
            mID = M.exp.metadata.mouseID;


            if mID == app.mouseID(value)
              
               if n == 0
               
                   history = (struct2cell(M.exp.metadata))';
                   n = 1;
               else  
                n = size(history,1);
                history(n+1,:) = (struct2cell(M.exp.metadata))';
               end
            end
          end
          
          app.UITable.Data = history(:,[1,7,10,9]);
          app.UITable.ColumnName = column([1,7,10,9]);
        end

        % Value changed function: ExperimentDropDown
        function selectExp(app, event)
            value = app.ExperimentDropDown.Value;
            
        end

        % Value changed function: UserDropDown
        function selectUser(app, event)
            value = app.UserDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_5 = uipanel(app.Panel_4);
            app.DateandtimeLabel = uilabel(app.Panel_5);
            app.DateandtimeLabel.Text = 'Date and time';
            app.saveLabel = uilabel(app.Panel_5);
            app.saveLabel.Text = 'save ';
            app.ExperimentDropDownLabel = uilabel(app.Panel_5);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Panel_5);
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @selectExp, true);
            app.UserDropDownLabel = uilabel(app.Panel_5);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.Panel_5);
            app.UserDropDown.ValueChangedFcn = createCallbackFcn(app, @selectUser, true);
            app.RunExperimentButton = uibutton(app.Panel_5, 'state');
            app.RunExperimentButton.ValueChangedFcn = createCallbackFcn(app, @RunExperiment, true);
            app.RunExperimentButton.Text = 'Run Experiment';
            app.Panel_6 = uipanel(app.Panel_4);
            app.GridLayout = uigridlayout(app.Panel_6);
            app.AgeLabel = uilabel(app.GridLayout);
            app.AgeLabel.Text = 'Age';
            app.DobLabel = uilabel(app.GridLayout);
            app.DobLabel.Text = 'Dob';
            app.GenotypeLabel = uilabel(app.GridLayout);
            app.GenotypeLabel.Text = 'Genotype';
            app.SexLabel = uilabel(app.GridLayout);
            app.SexLabel.Text = 'Sex';
            app.GroupLabel = uilabel(app.GridLayout);
            app.GroupLabel.Text = 'Group';
            app.Panel_7 = uipanel(app.Panel_4);
            app.MouseIDDropDownLabel = uilabel(app.Panel_7);
            app.MouseIDDropDownLabel.Text = 'Mouse ID ';
            app.MouseIDDropDown = uidropdown(app.Panel_7);
            app.MouseIDDropDown.Items = {'Select a Mouse', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectID, true);
            app.MouseIDDropDown.Value = 'Select a Mouse';
            app.ExperimentHistoryPanel = uipanel(app.Panel_4);
            app.ExperimentHistoryPanel.Title = 'Experiment History';
            app.UITable = uitable(app.ExperimentHistoryPanel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProgressTextLabel = uilabel(app.Panel_4);
            app.ProgressTextLabel.Text = 'ProgressText';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_mmc_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end